classdef ConfigGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConfigurationFileEditorGannetGUIV03UIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        SaveandContinuePanel           matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        SaveAndContinueButton          matlab.ui.control.Button
        ConfigurationfilenameexcludematextensionLabel  matlab.ui.control.Label
        ConfigFileName                 matlab.ui.control.EditField
        AdditionalOptionsPanel         matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        ReadWriteLabel                 matlab.ui.control.Label
        ReadWriteDropdown              matlab.ui.control.DropDown
        HideoutputfiguresLabel         matlab.ui.control.Label
        AppendPDFoutputsRequiresadditionstopathandextrapackageLabel  matlab.ui.control.Label
        HideDropdown                   matlab.ui.control.DropDown
        AppendDropdown                 matlab.ui.control.DropDown
        ExportToCSVDropdown            matlab.ui.control.DropDown
        JoinDropdown                   matlab.ui.control.DropDown
        SaveAsMATDropdown              matlab.ui.control.DropDown
        ExctractusefuldatafromMRS_structandexporttoacsvfileLabel  matlab.ui.control.Label
        JoinLabel                      matlab.ui.control.Label
        SaveMRS_structasamatfileLabel  matlab.ui.control.Label
        AnalysisParametersPanel        matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        WeightedAveragingDropdown      matlab.ui.control.DropDown
        FitResidualWaterDropdown       matlab.ui.control.DropDown
        RemoveResidualWaterDropdown    matlab.ui.control.DropDown
        EddyCorrectionMetaboliteDropdown  matlab.ui.control.DropDown
        EddyCorrectionWaterDropdown    matlab.ui.control.DropDown
        Voxel2Name                     matlab.ui.control.EditField
        Voxel1Name                     matlab.ui.control.EditField
        Voxel2namePRIAMdataonlyLabel   matlab.ui.control.Label
        Voxel1nameLabel                matlab.ui.control.Label
        AveragesubspectrausingweightedaveragingLabel  matlab.ui.control.Label
        FitresidualwatersignalintheOFFspectrumLabel  matlab.ui.control.Label
        PreAlignedDropdown             matlab.ui.control.DropDown
        UseprealignedspectraasreferencesLabel  matlab.ui.control.Label
        RemoveResidualWaterLabel       matlab.ui.control.Label
        EddyCorrectionMetaboliteLabel  matlab.ui.control.Label
        EddyCorrectionWaterLabel       matlab.ui.control.Label
        AlignmentMethodDropdown        matlab.ui.control.DropDown
        AlignmentmethodLabel           matlab.ui.control.Label
        LinebroadeningHzLabel          matlab.ui.control.Label
        LineBroadening                 matlab.ui.control.NumericEditField
        AcquisitionParametersPanel     matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        PRIAMAcquisitionCheckBox       matlab.ui.control.CheckBox
        PhantomAcquisitionCheckBox     matlab.ui.control.CheckBox
        OriginOfSequencesDropdown      matlab.ui.control.DropDown
        MetabListDropdown              matlab.ui.control.DropDown
        AcquisitionTypeDropdown        matlab.ui.control.DropDown
        AcquisitontypeDropDownLabel    matlab.ui.control.Label
        OriginofPhilipsMEGAPRESSGEHERMESsequencesLabel  matlab.ui.control.Label
        EditedmetabolitesofinterestLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        metabLists = {["GABA" "GABAGlx" "GSH" "Lac" "EtOH"] ["GABAGlx, GSH" "Lac, GSH" "EtOH, GABA, GSH"] ... % Stores possible values for metabolites of interest grouped by acquisition type
            "GABAGlx, GSH" ["GABA" "Glx" "GSH" "Lac" "EtOH"] ...
            ["GABA, GSH" "Glx, GSH" "Lac, GSH" "EtOH, GABA, GSH"]}
        gannetGUI;     % GUI that called and opened this one
        path;          % Path of passed in file
        name;          % Name of passed in file
    end
    
    methods (Access = private)
        
        % Converts from 'Yes' or 'No' to a logical that can be read by
        % Gannet
        function value = DropdownValue(~, dropdown)
            value = 0;
            if strcmp(dropdown.Value, 'Yes')
                value = 1;
            end
        end
        
        % Converts from a logical value to 'Yes' or 'No'
        function value = FillDropdownValue(~, boolean)
            value = 'No';
            if boolean == 1
                value = 'Yes';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, gannetGUI, path, fileName)
            % On startup, initialize the GUI with the default metabList and
            % information. If the GUI was called with a file, read
            % the data from that file and update the GUI's visual elements
            % accordingly
            
            app.MetabListDropdown.Items = app.metabLists{1};
            app.gannetGUI = gannetGUI;
            app.path = path;
            app.name = fileName;
            app.ConfigFileName.Value = fileName(1:end - 4);
            
            % If given file name is not empty, read information from that
            % file
            if ~isempty(fileName)
                filePath = fullfile(path, fileName);
                if isfile(filePath)
                    try
                        configFile = load(filePath, 'configFile');
                        configFile = configFile.configFile;
                    catch
                        uialert(app.ConfigurationFileEditorGannetGUIV03UIFigure, 'It looks like the selected .mat file is not a valid configuration file.', 'Invalid File Selected', 'Icon', 'warning')
                    end
                    try
                        app.PRIAMAcquisitionCheckBox.Value = configFile.isPRIAM;
                        if app.PRIAMAcquisitionCheckBox.Value
                            app.Voxel2Name.Enable = true;
                        end
                        app.PhantomAcquisitionCheckBox.Value = configFile.isPhantom;
                        app.AcquisitionTypeDropdown.Value = configFile.acquisitionType;
                        app.MetabListDropdown.Items = configFile.metabList;
                        app.MetabListDropdown.Value = strjoin(configFile.metabolitesOfInterest, ', ');
                        app.OriginOfSequencesDropdown.Value = configFile.originOfSequences;
                        
                        app.LineBroadening.Value = configFile.lineBroadening;
                        app.AlignmentMethodDropdown.Value = configFile.alignmentMethod;
                        app.PreAlignedDropdown.Value = FillDropdownValue(app, configFile.usePreAlignedRef);
                        app.EddyCorrectionWaterDropdown.Value = FillDropdownValue(app, configFile.ECCWater);
                        app.EddyCorrectionMetaboliteDropdown.Value = FillDropdownValue(app, configFile.ECCMetab);
                        app.RemoveResidualWaterDropdown.Value = FillDropdownValue(app, configFile.removeResidualWater);
                        app.FitResidualWaterDropdown.Value = FillDropdownValue(app, configFile.fitResidualWater);
                        app.WeightedAveragingDropdown.Value = FillDropdownValue(app, configFile.weightedAveraging);
                        app.Voxel1Name.Value = configFile.voxelNames{1};
                        if length(configFile.voxelNames) == 2
                            app.Voxel2Name.Value = configFile.voxelNames{2};
                        else
                            app.Voxel2Name.Value = 'voxel2';
                        end
                        app.SaveAsMATDropdown.Value = FillDropdownValue(app, configFile.saveAsMAT);
                        app.JoinDropdown.Value = FillDropdownValue(app, configFile.join);
                        app.ExportToCSVDropdown.Value = FillDropdownValue(app, configFile.exportToCSV);
                        app.HideDropdown.Value = FillDropdownValue(app, configFile.hide);
                        app.ReadWriteDropdown.Value = FillDropdownValue(app, configFile.readWriteAccess);

                        num = 1;
                        
                        % Some GUI options are mutually exclusive; check
                        % for them here and update
                        if strcmp(app.AcquisitionTypeDropdown.Value, 'HERCULES')
                            num = 3;
                            app.PhantomAcquisitionCheckBox.Enable = false;
                            app.PhantomAcquisitionCheckBox.Value = 0;
                        elseif strcmp(app.AcquisitionTypeDropdown.Value, 'HERMES')
                            num = 2;
                        elseif ~strcmp(app.AcquisitionTypeDropdown.Value, 'MEGA-PRESS')
                            uialert(app.ConfigurationFileEditorGannetGUIV03UIFigure, 'Invalid acquisition type given. Options are MEGA-PRESS, HERMES, or HERCULES.', 'Invalid Acquisition Type', 'Icon', 'info')
                            app.AcquisitionTypeDropdown.Value = 'MEGA-PRESS';
                            app.MetabListDropdown.Items = app.metabLists{1};
                        end
                        
                        % For Phantom data, there are different options for
                        % metabolites of interest
                        if app.PhantomAcquisitionCheckBox.Value
                            correctMetabList = app.metabLists{num + 3};
                        else
                            correctMetabList = app.metabLists{num};
                        end
                        
                        % Make sure that the given list of metabolites
                        % matches the possible metabolites of interest for
                        % the given acquisition type
                        if ~all(strcmp(app.MetabListDropdown.Items, correctMetabList))
                                uialert(app.ConfigurationFileEditorGannetGUIV03UIFigure, 'The given list of metabolites is incorrect for the chosen acquisition type.', 'Incorrect list of metabolites', 'Icon', 'info')
                                app.MetabListDropdown.Items = correctMetabList;
                        end

                    catch
                        uialert(app.ConfigurationFileEditorGannetGUIV03UIFigure, 'One or more fields could not be filled from the given configuration file. Please check that the file''s fields are properly named.', 'Error Loading File', 'Icon', 'warning')
                    end
                else
                    uialert(app.ConfigurationFileEditorGannetGUIV03UIFigure, strcat("The file ", filePath, " was not found."), "File Not Found", "Icon", "warning")
                end
            end
        end

        % Value changed function: AcquisitionTypeDropdown
        function AcquisitionTypeDropdownValueChanged(app, event)
            % Change the metabList based on the new acquisition type. Also
            % enable/disable the Phantom options based on
            % acquisition type

            % event.Source.Value is what the dropdown was changed to            
            newAcquisitionType = event.Source.Value;
            
            % num is assigned so the dropdown with options for metabolites
            % of interest is assigned easily

            if strcmp(newAcquisitionType, 'HERCULES')
                num = 3;
                app.PhantomAcquisitionCheckBox.Enable = false;
                app.PhantomAcquisitionCheckBox.Value = 0;
                app.OriginOfSequencesDropdown.Items = ["JHU" "Philips"];
            else
                if strcmp(newAcquisitionType, 'MEGA-PRESS')
                    num = 1;
                    app.OriginOfSequencesDropdown.Items = ["JHU" "Philips"];
                else
                    num = 2;
                    app.OriginOfSequencesDropdown.Items = ["JHU" "Philips" "Lythgoe"];
                end
                
                app.PhantomAcquisitionCheckBox.Enable = true;
            end
            
            if app.PhantomAcquisitionCheckBox.Value
                app.MetabListDropdown.Items = app.metabLists{num + 3};
            else
                app.MetabListDropdown.Items = app.metabLists{num};
            end
        end

        % Value changed function: PhantomAcquisitionCheckBox
        function PhantomAcquisitionCheckBoxValueChanged(app, event)
            % Enabling/Disabling the phantom option affects the possible
            % metabolites of interest
            acquisitionType = app.AcquisitionTypeDropdown.Value;
            
            num = 1;

            if strcmp(acquisitionType, 'HERCULES')
                num = 3;
            elseif strcmp(acquisitionType, 'HERMES')
                num = 2;
            end
            
            % If the data is from a phantom, HERCULES should not be a valid
            % acquisition type. The list of possible metabolites should
            % also be updated
            if app.PhantomAcquisitionCheckBox.Value
                app.AcquisitionTypeDropdown.Items = app.AcquisitionTypeDropdown.Items(1:end - 1);
                app.MetabListDropdown.Items = app.metabLists{num + 3};
            else
                app.AcquisitionTypeDropdown.Items = [app.AcquisitionTypeDropdown.Items "HERCULES"];
                app.MetabListDropdown.Items = app.metabLists{num};
            end
        end

        % Value changed function: PRIAMAcquisitionCheckBox
        function PRIAMAcquisitionCheckBoxValueChanged(app, event)
            % If PRIAM is chosen, 2 voxels need to be named
            if app.PRIAMAcquisitionCheckBox.Value
                app.Voxel2Name.Enable = true;
            else
                app.Voxel2Name.Enable = false;
            end
        end

        % Button pushed function: SaveAndContinueButton
        function SaveAndContinueButtonPushed(app, event)
            % If the file name is not empty, save the data from the GUI
            % into a Matlab structure that can then be loaded and read
            if ~isempty(strip(app.ConfigFileName.Value))
                configFile = struct;
                
                configFile.isPRIAM = app.PRIAMAcquisitionCheckBox.Value;
                configFile.isPhantom = app.PhantomAcquisitionCheckBox.Value;
                configFile.acquisitionType = app.AcquisitionTypeDropdown.Value;
                configFile.metabList = app.MetabListDropdown.Items;
                configFile.metabolitesOfInterest = cellstr(strsplit(app.MetabListDropdown.Value, ', '));
                configFile.originOfSequences = convertStringsToChars(app.OriginOfSequencesDropdown.Value);
                
                configFile.lineBroadening = app.LineBroadening.Value;
                configFile.alignmentMethod = app.AlignmentMethodDropdown.Value;
                configFile.usePreAlignedRef = DropdownValue(app, app.PreAlignedDropdown);
                configFile.ECCWater = DropdownValue(app, app.EddyCorrectionWaterDropdown);
                configFile.ECCMetab = DropdownValue(app, app.EddyCorrectionMetaboliteDropdown);
                configFile.removeResidualWater = DropdownValue(app, app.RemoveResidualWaterDropdown);
                configFile.fitResidualWater = DropdownValue(app, app.FitResidualWaterDropdown);
                configFile.weightedAveraging = DropdownValue(app, app.WeightedAveragingDropdown);
                
                configFile.voxelNames{1} = app.Voxel1Name.Value;
                if (app.Voxel2Name.Enable == true)
                    configFile.voxelNames{2} = app.Voxel2Name.Value;
                end
                
                configFile.saveAsMAT = DropdownValue(app, app.SaveAsMATDropdown);
                configFile.join = DropdownValue(app, app.JoinDropdown);
                configFile.exportToCSV = DropdownValue(app, app.ExportToCSVDropdown);
                configFile.append = DropdownValue(app, app.AppendDropdown);
                configFile.hide = DropdownValue(app, app.HideDropdown);
                
                configFile.readWriteAccess = DropdownValue(app, app.ReadWriteDropdown);

                configFile.isHERMES = 0;
                configFile.isHERCULES = 0;
                
                if strcmp(app.AcquisitionTypeDropdown.Value, 'HERCULES')
                    configFile.isHERMES = 1;
                    configFile.isHERCULES = 1;
                elseif strcmp(app.AcquisitionTypeDropdown.Value, 'HERMES')
                    configFile.isHERMES = 1;
                end
                
                if ~exist(app.path, 'dir')
                    mkdir(app.path)
                end
                
                configFilePath = fullfile(app.path, strcat(app.ConfigFileName.Value, '.mat'));
                
                % If the name is changed and is not empty
                if ~strcmp(fullfile(app.path, app.name), configFilePath) && ~isempty(app.name)
                    choice = uiconfirm(app.ConfigurationFileEditorGannetGUIV03UIFigure, 'It looks like you renamed your configuration file. Would you like to save this file as a re-named copy or re-name the original file?', 'Copy or Re-Name File?', 'Options', {'Re-Name File', 'Create a Copy', 'Cancel'}, 'Icon', 'info');
                    
                    % Check to see if a selection was made. If not, do not
                    % save a file
                    if ~isequal(choice, 'Cancel')
                        % Overwrite exisitng config file with the new config
                        % file (functionally the same as re-naming)
                        if strcmp(choice, 'Re-Name File')
                            save(fullfile(app.path, app.name), "configFile")
                            movefile(fullfile(app.path, app.name), configFilePath)
                            % Save a new one with the new name
                        else
                            save(configFilePath, "configFile")
                        end
                        wait = uiconfirm(app.ConfigurationFileEditorGannetGUIV03UIFigure, 'Your configuration file was successfully updated.', 'File Saved', 'Icon', 'success', 'Options', {'OK'});
                    else
                        return
                    end

                % If app.name is empty, that means a new config file was
                % created. No issue in saving/re-naming
                elseif isempty(app.name)
                    save(configFilePath, "configFile");
                    wait = uiconfirm(app.ConfigurationFileEditorGannetGUIV03UIFigure, 'Your configuration file was successfully saved.', 'File Saved', 'Icon', 'success', 'Options', {'OK'});
                
                % If app.name is not empty but it is unchanged, that
                % signals that the config file was just updated. No issue
                % saving
                else
                    save(configFilePath, "configFile");
                    wait = uiconfirm(app.ConfigurationFileEditorGannetGUIV03UIFigure, 'Your configuration file was successfully updated.', 'File Saved', 'Icon', 'success', 'Options', {'OK'});
                end
                
                app.gannetGUI.UpdateConfigList;
                app.gannetGUI.EnableDisableContinue;
                delete(app)
            else
                uialert(app.ConfigurationFileEditorGannetGUIV03UIFigure, "Please enter a valid file name.", "Invalid File Name", "Icon", "warning")
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConfigurationFileEditorGannetGUIV03UIFigure = uifigure('Visible', 'off');
            app.ConfigurationFileEditorGannetGUIV03UIFigure.Name = 'Configuration File Editor | Gannet GUI V0.3';
            app.GridLayout = uigridlayout(app.ConfigurationFileEditorGannetGUIV03UIFigure);
            app.AcquisitionParametersPanel = uipanel(app.GridLayout);
            app.AcquisitionParametersPanel.Title = 'Acquisition Parameters';
            app.GridLayout2 = uigridlayout(app.AcquisitionParametersPanel);
            app.EditedmetabolitesofinterestLabel = uilabel(app.GridLayout2);
            app.EditedmetabolitesofinterestLabel.Text = 'Edited metabolite(s) of interest:';
            app.OriginofPhilipsMEGAPRESSGEHERMESsequencesLabel = uilabel(app.GridLayout2);
            app.OriginofPhilipsMEGAPRESSGEHERMESsequencesLabel.Text = 'Origin of Philips MEGA-PRESS/GE HERMES sequences:';
            app.AcquisitontypeDropDownLabel = uilabel(app.GridLayout2);
            app.AcquisitontypeDropDownLabel.Text = 'Acquisiton type:';
            app.AcquisitionTypeDropdown = uidropdown(app.GridLayout2);
            app.AcquisitionTypeDropdown.Items = {'MEGA-PRESS', 'HERMES', 'HERCULES'};
            app.AcquisitionTypeDropdown.ValueChangedFcn = createCallbackFcn(app, @AcquisitionTypeDropdownValueChanged, true);
            app.AcquisitionTypeDropdown.Value = 'MEGA-PRESS';
            app.MetabListDropdown = uidropdown(app.GridLayout2);
            app.OriginOfSequencesDropdown = uidropdown(app.GridLayout2);
            app.OriginOfSequencesDropdown.Items = {'JHU', 'Philips', 'Lythgoe'};
            app.OriginOfSequencesDropdown.Value = 'JHU';
            app.PhantomAcquisitionCheckBox = uicheckbox(app.GridLayout2);
            app.PhantomAcquisitionCheckBox.ValueChangedFcn = createCallbackFcn(app, @PhantomAcquisitionCheckBoxValueChanged, true);
            app.PhantomAcquisitionCheckBox.Text = 'Phantom acquisition';
            app.PRIAMAcquisitionCheckBox = uicheckbox(app.GridLayout2);
            app.PRIAMAcquisitionCheckBox.ValueChangedFcn = createCallbackFcn(app, @PRIAMAcquisitionCheckBoxValueChanged, true);
            app.PRIAMAcquisitionCheckBox.Text = 'PRIAM acquisition';
            app.AnalysisParametersPanel = uipanel(app.GridLayout);
            app.AnalysisParametersPanel.Title = 'Analysis Parameters';
            app.GridLayout3 = uigridlayout(app.AnalysisParametersPanel);
            app.LineBroadening = uieditfield(app.GridLayout3, 'numeric');
            app.LineBroadening.Limits = [0 Inf];
            app.LineBroadening.Value = 3;
            app.LinebroadeningHzLabel = uilabel(app.GridLayout3);
            app.LinebroadeningHzLabel.Text = 'Line-broadening (Hz):';
            app.AlignmentmethodLabel = uilabel(app.GridLayout3);
            app.AlignmentmethodLabel.Text = 'Alignment method:';
            app.AlignmentMethodDropdown = uidropdown(app.GridLayout3);
            app.AlignmentMethodDropdown.Items = {'RobustSpecReg', 'SpecReg', 'SpecRegHERMES', 'Cr', 'Cho', 'NAA', 'H2O', 'CrOFF', 'None'};
            app.AlignmentMethodDropdown.Value = 'RobustSpecReg';
            app.EddyCorrectionWaterLabel = uilabel(app.GridLayout3);
            app.EddyCorrectionWaterLabel.Text = 'Perform eddy current correction on water data?';
            app.EddyCorrectionMetaboliteLabel = uilabel(app.GridLayout3);
            app.EddyCorrectionMetaboliteLabel.Text = 'Perform eddy current correction on metabolite data?';
            app.RemoveResidualWaterLabel = uilabel(app.GridLayout3);
            app.RemoveResidualWaterLabel.Text = 'Remove residual water signal using HSVD?';
            app.UseprealignedspectraasreferencesLabel = uilabel(app.GridLayout3);
            app.UseprealignedspectraasreferencesLabel.Text = 'Use pre-aligned spectra as references?';
            app.PreAlignedDropdown = uidropdown(app.GridLayout3);
            app.PreAlignedDropdown.Items = {'Yes', 'No'};
            app.PreAlignedDropdown.Value = 'No';
            app.FitresidualwatersignalintheOFFspectrumLabel = uilabel(app.GridLayout3);
            app.FitresidualwatersignalintheOFFspectrumLabel.Text = 'Fit residual water signal in the OFF spectrum?';
            app.AveragesubspectrausingweightedaveragingLabel = uilabel(app.GridLayout3);
            app.AveragesubspectrausingweightedaveragingLabel.Text = 'Average subspectra using weighted averaging?';
            app.Voxel1nameLabel = uilabel(app.GridLayout3);
            app.Voxel1nameLabel.Text = 'Voxel 1 name:';
            app.Voxel2namePRIAMdataonlyLabel = uilabel(app.GridLayout3);
            app.Voxel2namePRIAMdataonlyLabel.Text = 'Voxel 2 name (PRIAM data only):';
            app.Voxel1Name = uieditfield(app.GridLayout3, 'text');
            app.Voxel1Name.Value = 'voxel1';
            app.Voxel2Name = uieditfield(app.GridLayout3, 'text');
            app.Voxel2Name.Enable = 'off';
            app.Voxel2Name.Value = 'voxel2';
            app.EddyCorrectionWaterDropdown = uidropdown(app.GridLayout3);
            app.EddyCorrectionWaterDropdown.Items = {'Yes', 'No'};
            app.EddyCorrectionWaterDropdown.Value = 'Yes';
            app.EddyCorrectionMetaboliteDropdown = uidropdown(app.GridLayout3);
            app.EddyCorrectionMetaboliteDropdown.Items = {'Yes', 'No'};
            app.EddyCorrectionMetaboliteDropdown.Value = 'No';
            app.RemoveResidualWaterDropdown = uidropdown(app.GridLayout3);
            app.RemoveResidualWaterDropdown.Items = {'Yes', 'No'};
            app.RemoveResidualWaterDropdown.Value = 'Yes';
            app.FitResidualWaterDropdown = uidropdown(app.GridLayout3);
            app.FitResidualWaterDropdown.Items = {'Yes', 'No'};
            app.FitResidualWaterDropdown.Value = 'No';
            app.WeightedAveragingDropdown = uidropdown(app.GridLayout3);
            app.WeightedAveragingDropdown.Items = {'Yes', 'No'};
            app.WeightedAveragingDropdown.Value = 'Yes';
            app.AdditionalOptionsPanel = uipanel(app.GridLayout);
            app.AdditionalOptionsPanel.Title = 'Additional Options';
            app.GridLayout4 = uigridlayout(app.AdditionalOptionsPanel);
            app.SaveMRS_structasamatfileLabel = uilabel(app.GridLayout4);
            app.SaveMRS_structasamatfileLabel.Text = 'Save MRS_struct as a .mat file?';
            app.JoinLabel = uilabel(app.GridLayout4);
            app.JoinLabel.Text = 'Join multiple files?';
            app.ExctractusefuldatafromMRS_structandexporttoacsvfileLabel = uilabel(app.GridLayout4);
            app.ExctractusefuldatafromMRS_structandexporttoacsvfileLabel.Text = 'Exctract useful data from MRS_struct and export to a .csv file?';
            app.SaveAsMATDropdown = uidropdown(app.GridLayout4);
            app.SaveAsMATDropdown.Items = {'Yes', 'No'};
            app.SaveAsMATDropdown.Value = 'No';
            app.JoinDropdown = uidropdown(app.GridLayout4);
            app.JoinDropdown.Items = {'Yes', 'No'};
            app.JoinDropdown.Value = 'No';
            app.ExportToCSVDropdown = uidropdown(app.GridLayout4);
            app.ExportToCSVDropdown.Items = {'Yes', 'No'};
            app.ExportToCSVDropdown.Value = 'No';
            app.AppendDropdown = uidropdown(app.GridLayout4);
            app.AppendDropdown.Items = {'Yes', 'No'};
            app.AppendDropdown.Value = 'No';
            app.HideDropdown = uidropdown(app.GridLayout4);
            app.HideDropdown.Items = {'Yes', 'No'};
            app.HideDropdown.Value = 'No';
            app.AppendPDFoutputsRequiresadditionstopathandextrapackageLabel = uilabel(app.GridLayout4);
            app.AppendPDFoutputsRequiresadditionstopathandextrapackageLabel.Text = 'Append PDF outputs? Requires additions to path and extra package (see Gannet documentation)';
            app.HideoutputfiguresLabel = uilabel(app.GridLayout4);
            app.HideoutputfiguresLabel.Text = 'Hide output figures?';
            app.ReadWriteDropdown = uidropdown(app.GridLayout4);
            app.ReadWriteDropdown.Items = {'Yes', 'No'};
            app.ReadWriteDropdown.Value = 'Yes';
            app.ReadWriteLabel = uilabel(app.GridLayout4);
            app.ReadWriteLabel.Text = 'Do you have full read, write, and execute access to the input data?';
            app.SaveandContinuePanel = uipanel(app.GridLayout);
            app.SaveandContinuePanel.Title = 'Save and Continue';
            app.GridLayout5 = uigridlayout(app.SaveandContinuePanel);
            app.ConfigFileName = uieditfield(app.GridLayout5, 'text');
            app.ConfigurationfilenameexcludematextensionLabel = uilabel(app.GridLayout5);
            app.ConfigurationfilenameexcludematextensionLabel.Text = 'Configuration file name (exclude .mat extension):';
            app.SaveAndContinueButton = uibutton(app.GridLayout5, 'push');
            app.SaveAndContinueButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAndContinueButtonPushed, true);
            app.SaveAndContinueButton.Text = 'Save and Continue';
            % Show the figure after all components are created
            app.ConfigurationFileEditorGannetGUIV03UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConfigGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConfigurationFileEditorGannetGUIV03UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConfigurationFileEditorGannetGUIV03UIFigure)
        end
    end
end