classdef GannetGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GannetGUIV03UIFigure           matlab.ui.Figure
        MainGridLayout                 matlab.ui.container.GridLayout
        BottomGridLayout               matlab.ui.container.GridLayout
        GridLayout5                    matlab.ui.container.GridLayout
        LoadExistingWorkspaceButton    matlab.ui.control.Button
        ViewEditExistingConfigurationButton  matlab.ui.control.Button
        ImportConfigurationButton      matlab.ui.control.Button
        SaveCurrentWorkspaceButton     matlab.ui.control.Button
        CreateNewConfigurationButton   matlab.ui.control.Button
        ResetWorkspaceButton           matlab.ui.control.Button
        ContinueButton                 matlab.ui.control.Button
        DataSelectionPanel             matlab.ui.container.Panel
        DataAndHeaderGridLayout        matlab.ui.container.GridLayout
        DataGridLayout                 matlab.ui.container.GridLayout
        HeaderGridLayout               matlab.ui.container.GridLayout
        RemoveRowLabel                 matlab.ui.control.Label
        ConfigurationFileLabel         matlab.ui.control.Label
        AnatomicalImageFileLabel       matlab.ui.control.Label
        WaterReferenceFileLabel        matlab.ui.control.Label
        MetaboliteFileLabel            matlab.ui.control.Label
        StudySelectionGridLayout       matlab.ui.container.GridLayout
        HelpAboutButton                matlab.ui.control.Button
        NameAndPathPanel               matlab.ui.container.Panel
        NameAndPathGridLayout          matlab.ui.container.GridLayout
        WorkspaceNameEditField         matlab.ui.control.EditField
        GridLayout7                    matlab.ui.container.GridLayout
        ChooseDerivativesFolderButton  matlab.ui.control.Button
        DerivativesFolderEditField     matlab.ui.control.EditField
        WorkspaceLabel                 matlab.ui.control.Label
        DerivativesFolderLabel         matlab.ui.control.Label
        GridLayout6                    matlab.ui.container.GridLayout
        InputDataEditField             matlab.ui.control.EditField
        ChooseInputDataButton          matlab.ui.control.Button
        InputDataLabel                 matlab.ui.control.Label
        GannetLogo                     matlab.ui.control.Image
    end


    properties (Access = private)
        mrsStudyStruct       % Large structure storing all MRS-related data from a study directory
        rowObjects           % Structure that stores GUI elements
        configGUI            % GUI that creates/edits configuration files
        subjectSelectionGUI  % GUI where user chooses which subjects to process
    end

    methods (Access = private)
        % Deletes user interface elements from the GUI
        function DeleteUIElements(app, row)
            uiElements = ["metab" "ref" "t1" "config" "removeRow"];

            for uiElementNum = 1:5
                delete(app.rowObjects.dataRows(row).(uiElements(uiElementNum)));
            end

            try
                delete(app.rowObjects.dataRows(row).createConfig)
            catch
            end
        end

        function ResetGUI(app)
            app.InputDataEditField.Value = "";
            app.DerivativesFolderEditField.Value = "";
            app.WorkspaceNameEditField.Value = "";
            app.mrsStudyStruct = struct;

            ClearWorkspace(app);
        end

        % Adds a new row to the GUI's table and stores it in the
        % appropriate structure
        function AddRow(app, ~)
            app.DataGridLayout.RowHeight = [app.DataGridLayout.RowHeight 40];
            app.rowObjects.addRow.Layout.Row = app.rowObjects.addRow.Layout.Row + 1;

            row = app.rowObjects.numRows + 1;

            configFiles = app.mrsStudyStruct.configFiles;
            configList = regexprep(configFiles, '.mat', '');

            dropdownNames = ["metab" "ref" "t1" "config"];
            dropdownItems = {[app.mrsStudyStruct.uniqueScans.metab] app.mrsStudyStruct.uniqueScans(1).ref app.mrsStudyStruct.uniqueScans(1).t1 configList};

            for dropdownNum = 1:4
                currentDropdown = uidropdown(app.DataGridLayout);
                currentDropdown.Layout.Column = dropdownNum;
                currentDropdown.Layout.Row = row;
                currentDropdown.Items = dropdownItems{dropdownNum};
                currentDropdown.Tag = int2str(row);

                if dropdownNum == 1
                    currentDropdown.ValueChangedFcn = createCallbackFcn(app, @MetabDropdownValueChanged, true);
                end

                app.rowObjects.dataRows(row).(dropdownNames(dropdownNum)) = currentDropdown;
            end

            if isempty(app.mrsStudyStruct.configFiles)
                createConfigButton = uibutton(app.DataGridLayout);
                createConfigButton.Layout.Column = 4;
                createConfigButton.Layout.Row = row;
                createConfigButton.Text = "Create Configuration";
                createConfigButton.ButtonPushedFcn = createCallbackFcn(app, @CreateConfig, true);

                app.rowObjects.dataRows(row).createConfig = createConfigButton;

                app.rowObjects.dataRows(row).config.Enable = false;
                app.rowObjects.dataRows(row).config.Visible = false;
            end

            currentRemoveRowButton = uibutton(app.DataGridLayout);
            currentRemoveRowButton.BackgroundColor = [0.9 0.5 0.5];
            currentRemoveRowButton.Layout.Column = 5;
            currentRemoveRowButton.Layout.Row = row;
            currentRemoveRowButton.Tag = int2str(row);
            currentRemoveRowButton.Text = "X";
            currentRemoveRowButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveRow, true);

            app.rowObjects.dataRows(row).removeRow = currentRemoveRowButton;
            app.rowObjects.numRows = app.rowObjects.numRows + 1;

            AdjustHeaderColumnWidths(app);
            EnableDisableContinue(app);
        end

        % Removes a selected row from the GUI's table and shifts rows
        % beneath it upwards
        function RemoveRow(app, event)
            uiElements = ["metab" "ref" "t1" "config" "removeRow"];

            rowNum = str2double(event.Source.Tag);

            if app.rowObjects.numRows > 1
                DeleteUIElements(app, rowNum);

                for row = (rowNum + 1):app.rowObjects.numRows
                    for uiElementNum = 1:5
                        if uiElementNum == 1
                            app.rowObjects.dataRows(row).metab.Tag = int2str(row - 1);
                        end

                        app.rowObjects.dataRows(row).(uiElements(uiElementNum)).Layout.Row = row - 1;
                        try
                            app.rowObjects.dataRows(row).createConfig.Layout.Row = row - 1;
                        catch
                        end
                    end

                    app.rowObjects.dataRows(row).removeRow.Tag = int2str(row - 1);
                end

                app.rowObjects.addRow.Layout.Row = app.rowObjects.addRow.Layout.Row - 1;

                app.rowObjects.dataRows(rowNum) = [];
                app.rowObjects.numRows = app.rowObjects.numRows - 1;

                app.DataGridLayout.RowHeight = app.DataGridLayout.RowHeight(1:end - 1);
                AdjustHeaderColumnWidths(app);
                EnableDisableContinue(app);
            else
                uialert(app.GannetGUIV03UIFigure,'Cannot remove all rows.', 'Warning', 'Icon', 'warning')
            end
        end

        % Given a scan modality and an array of the unique scan sequences
        % found using that modality, checks if each subject has each
        % sequence
        function CheckFileExistence(app, scanType, uniqueArray)
            for subNum = 1:app.mrsStudyStruct.numSubs
                subName = app.mrsStudyStruct.subjects(subNum).subName;
                app.mrsStudyStruct.allScansTable(subNum).subName = subName;

                for scanNum = 1:length(uniqueArray)
                    currentScan = uniqueArray(scanNum);
                    possibleSesName = extractBefore(currentScan, '_');

                    if contains(possibleSesName, 'ses-')
                        sesName = possibleSesName;
                    else
                        sesName = '';
                    end

                    if strcmp(scanType, 't1')
                        modality = 'anat';
                    elseif (strcmp(scanType, 'metab') || strcmp(scanType, 'ref'))
                        modality = 'mrs';
                    end

                    scanName = strcat(subName, '_', currentScan);
                    filePath = fullfile(app.mrsStudyStruct.inputData, subName, sesName, modality, scanName);
                    fieldName = strcat(scanType, 'Scan', int2str(scanNum));

                    app.mrsStudyStruct.key(currentScan) = fieldName;

                    app.mrsStudyStruct.allScansTable(subNum).(fieldName).filePath = filePath;
                    app.mrsStudyStruct.allScansTable(subNum).(fieldName).isFile = isfile(filePath);
                end
            end
        end

        % Adjusts the widths of the header columns to accommodate for the
        % appearance/disappearance of the scroll bar in the GUI
        function AdjustHeaderColumnWidths(app)
            position = app.GannetGUIV03UIFigure.Position;
            height = 2 * floor(1/5 * position(4) - 12) + 1;
            tableHeight = sum(cell2mat(app.DataGridLayout.RowHeight)) + 10 * (length(app.DataGridLayout.RowHeight) + 1);

            if tableHeight > height
                app.HeaderGridLayout.ColumnWidth = {'1x' '1x' '1x' '1x' '0.3x' 7};
            else
                app.HeaderGridLayout.ColumnWidth = {'1x' '1x' '1x' '1x' '0.3x'};
            end
        end

        % Update the items in the ref and config dropdowns so that they
        % only show items from the same session as the metab dropdown
        function MetabDropdownValueChanged(app, event)
            rowNum = str2double(event.Source.Tag);
            value = event.Source.Value;

            for ses = 1:app.mrsStudyStruct.numSessions
                if any(strcmp(app.mrsStudyStruct.uniqueScans(ses).metab, value))
                    break
                end
            end

            app.rowObjects.dataRows(rowNum).ref.Items = app.mrsStudyStruct.uniqueScans(ses).ref;
            app.rowObjects.dataRows(rowNum).t1.Items = app.mrsStudyStruct.uniqueScans(ses).t1;

            EnableDisableContinue(app);
        end

        % Clears visual workspace and resets the data-storing structures of
        % the GUI
        function ClearWorkspace(app)
            try
                for row = 1:app.rowObjects.numRows
                    DeleteUIElements(app, row);
                end

                delete(app.rowObjects.addRow);
            catch
            end
            
            app.rowObjects = struct;
            app.rowObjects.dataRows = struct;
            app.rowObjects.numRows = 0;
            EnableDisable(app, false);
            app.ContinueButton.Enable = false;
        end

        % If a new study, builds a default table with as many rows as
        % unique metab sequences found. If not, builds a table with the
        % given number of rows
        function BuildWorkspace(app, newStudy, numRows)
            addRowButton = uibutton(app.DataGridLayout, 'push');
            addRowButton.BackgroundColor = [0.8 0.8 0.8];
            addRowButton.Layout.Row = 1;
            addRowButton.Layout.Column = 1;
            addRowButton.Text = "(Add Row)";
            addRowButton.FontAngle = 'italic';
            addRowButton.ButtonPushedFcn = createCallbackFcn(app, @AddRow, true);

            app.rowObjects.addRow = addRowButton;

            builtRows = 0;

            if newStudy
                for ses = 1:app.mrsStudyStruct.numSessions
                    for row = 1:length(app.mrsStudyStruct.uniqueScans(ses).metab)
                        AddRow(app);
                        builtRows = builtRows + 1;
                        app.rowObjects.dataRows(builtRows).metab.Value = app.mrsStudyStruct.uniqueScans(ses).metab(row);
                        app.rowObjects.dataRows(builtRows).ref.Items = app.mrsStudyStruct.uniqueScans(ses).ref;
                        app.rowObjects.dataRows(builtRows).t1.Items = app.mrsStudyStruct.uniqueScans(ses).t1;
                    end
                end
            else
                builtRows = numRows;
                for row = 1:numRows
                    AddRow(app);
                end
            end

            if builtRows == 0
                uialert(app.GannetGUIV03UIFigure,'No metabolite files found in the given directory.', 'Warning', 'Icon', 'warning')
                ClearWorkspace(app);
                EnableDisable(app, false);
            end
        end

        % Given a study directory, load its data into the mrsStudyStruct
        % and build the accompanying table
        function LoadInputData(app, studyDir)
            ResetGUI(app);

            app.mrsStudyStruct.inputData = studyDir;
            app.InputDataEditField.Value = studyDir;
            app.WorkspaceNameEditField.Enable = false;

            studySubFolders = dir(studyDir);
            studySubFolders = [studySubFolders(1:end).name ""];
            studySubFolders = studySubFolders(1:end - 1);

            subFolders = studySubFolders(contains(studySubFolders, 'sub'));
            app.mrsStudyStruct.numSubs = length(subFolders);

            app.mrsStudyStruct.subjects = struct;
            app.mrsStudyStruct.sessions = strings;
            app.mrsStudyStruct.anyWithoutSessions = false;

            for subNum = 1:app.mrsStudyStruct.numSubs
                subName = subFolders(subNum);
                insideSubFolder = dir(fullfile(studyDir, subName));
                insideSubFolder = insideSubFolder([insideSubFolder(1:end).isdir]);
                insideSubFolder = [insideSubFolder(1:end).name ""];
                insideSubFolder = insideSubFolder(1:end - 1);

                sessions = insideSubFolder(contains(insideSubFolder, 'ses'));
                numSessions = length(sessions);

                app.mrsStudyStruct.subjects(subNum).subName = subName;

                if ~isempty(sessions)
                    app.mrsStudyStruct.subjects(subNum).sessionFolderPresent = true;
                    app.mrsStudyStruct.subjects(subNum).numSessions = numSessions;

                    for ses = 1:numSessions
                        if ~contains(app.mrsStudyStruct.sessions, sessions(ses))
                            app.mrsStudyStruct.sessions(end + 1) = sessions(ses);
                        end
                    end
                else
                    app.mrsStudyStruct.anyWithoutSessions = true;
                    app.mrsStudyStruct.subjects(subNum).sessionFolderPresent = false;
                    app.mrsStudyStruct.subjects(subNum).numSessions = 1;
                end
            end

            app.mrsStudyStruct.sessions = app.mrsStudyStruct.sessions(2:end);
            app.mrsStudyStruct.numSessions = length(app.mrsStudyStruct.sessions) + app.mrsStudyStruct.anyWithoutSessions;

            app.mrsStudyStruct.allScans = struct;
            app.mrsStudyStruct.allScans.metab = [];
            app.mrsStudyStruct.allScans.ref = [];
            app.mrsStudyStruct.allScans.t1 = [];

            for subNum = 1:app.mrsStudyStruct.numSubs
                subName = subFolders(subNum);

                if app.mrsStudyStruct.subjects(subNum).sessionFolderPresent
                    currentFolder = fullfile(studyDir, subName, 'ses-*');
                else
                    currentFolder = fullfile(studyDir, subName);
                end

                mrsScans = [dir(fullfile(currentFolder, 'mrs', 'sub-*.dat')); ...
                    dir(fullfile(currentFolder, 'mrs', 'sub-*.sdat')); ...
                    dir(fullfile(currentFolder, 'mrs', 'sub-*.7'))];
                mrsScanNames = [mrsScans(1:end).name ""];
                mrsScanNames = mrsScanNames(1:end - 1);

                metabScans = mrsScanNames(~contains(mrsScanNames, ["type-ref", "spec-uns"]));
                refScans = mrsScanNames(contains(mrsScanNames, ["type-ref", "spec-uns"]));

                t1Files = dir(fullfile(currentFolder,'anat','sub-*_T1w.nii'));
                t1FileNames = [t1Files(1:end).name ""];
                t1FileNames = t1FileNames(1:end - 1);

                app.mrsStudyStruct.subjects(subNum).metab = metabScans;
                app.mrsStudyStruct.subjects(subNum).ref = refScans;
                app.mrsStudyStruct.subjects(subNum).t1 = t1FileNames;

                numOfTotalMetabScans = length([app.mrsStudyStruct.allScans.metab]);
                numOfTotalRefScans = length([app.mrsStudyStruct.allScans.ref]);
                numOfTotalT1Scans = length([app.mrsStudyStruct.allScans.t1]);

                largest = max([length(metabScans) length(refScans) length(t1FileNames)]);

                for index = 1:largest
                    try
                        app.mrsStudyStruct.allScans(numOfTotalMetabScans + index).metab = metabScans(index);
                    catch
                    end
                    try
                        app.mrsStudyStruct.allScans(numOfTotalRefScans + index).ref = refScans(index);
                    catch
                    end
                    try
                        app.mrsStudyStruct.allScans(numOfTotalT1Scans + index).t1 = t1FileNames(index);
                    catch
                    end
                end
            end

            app.mrsStudyStruct.uniqueScans = struct;

            if ~isempty([app.mrsStudyStruct.allScans.metab])
                allUniqueMetabs = unique(extractAfter([app.mrsStudyStruct.allScans.metab], '_'));
            else
                allUniqueMetabs = strings(0);
            end
            if ~isempty([app.mrsStudyStruct.allScans.ref])
                allUniqueRefs = unique(extractAfter([app.mrsStudyStruct.allScans.ref], '_'));
            else
                allUniqueRefs = strings(0);
            end
            if ~isempty([app.mrsStudyStruct.allScans.t1])
                allUniqueT1s = unique(extractAfter([app.mrsStudyStruct.allScans.t1], '_'));
            else
                allUniqueT1s = strings(0);
            end

            if app.mrsStudyStruct.anyWithoutSessions
                app.mrsStudyStruct.uniqueScans(1).sesName = 'noSessionName';
                app.mrsStudyStruct.uniqueScans(1).metab = allUniqueMetabs(~contains(allUniqueMetabs, 'ses-'));
                app.mrsStudyStruct.uniqueScans(1).ref = ["None" allUniqueRefs(~contains(allUniqueRefs, 'ses-'))];
                app.mrsStudyStruct.uniqueScans(1).t1 = ["None" allUniqueT1s(~contains(allUniqueT1s, 'ses-'))];

                for ses = 1:length(app.mrsStudyStruct.sessions)
                    sesName = app.mrsStudyStruct.sessions(ses);

                    app.mrsStudyStruct.uniqueScans(ses + 1).sesName = sesName;
                    app.mrsStudyStruct.uniqueScans(ses + 1).metab = allUniqueMetabs(contains(allUniqueMetabs, sesName));
                    app.mrsStudyStruct.uniqueScans(ses + 1).ref = ["None" allUniqueRefs(contains(allUniqueRefs, sesName))];
                    app.mrsStudyStruct.uniqueScans(ses + 1).t1 = ["None" allUniqueT1s(contains(allUniqueT1s, sesName))];
                end
            else
                for ses = 1:length(app.mrsStudyStruct.sessions)
                    sesName = app.mrsStudyStruct.sessions(ses);

                    app.mrsStudyStruct.uniqueScans(ses).sesName = sesName;
                    app.mrsStudyStruct.uniqueScans(ses).metab = allUniqueMetabs(contains(allUniqueMetabs, sesName));
                    app.mrsStudyStruct.uniqueScans(ses).ref = ["None" allUniqueRefs(contains(allUniqueRefs, sesName))];
                    app.mrsStudyStruct.uniqueScans(ses).t1 = ["None" allUniqueT1s(contains(allUniqueT1s, sesName))];
                end
            end

            app.mrsStudyStruct.allScansTable = struct;
            app.mrsStudyStruct.key = containers.Map;

            CheckFileExistence(app, 'metab', allUniqueMetabs);
            CheckFileExistence(app, 'ref', allUniqueRefs);
            CheckFileExistence(app, 't1', allUniqueT1s);

            app.ChooseDerivativesFolderButton.Enable = true;
            app.DerivativesFolderEditField.Enable = true;
        end

        % Toggles the interactivity of GUI buttons that should only be
        % enabled when a study has been selected
        function EnableDisable(app, bool)
            app.ResetWorkspaceButton.Enable = bool;
            app.SaveCurrentWorkspaceButton.Enable = bool;
            app.CreateNewConfigurationButton.Enable = bool;
            app.ViewEditExistingConfigurationButton.Enable = bool;
            app.ImportConfigurationButton.Enable = bool;
            app.LoadExistingWorkspaceButton.Enable = bool;
        end

        function CreateConfig(app, ~, varargin)
            if length(varargin) == 2
                path = convertStringsToChars(varargin{1});
                name = convertStringsToChars(varargin{2});
            else
                path = fullfile(app.mrsStudyStruct.derivativesFolder, 'gannet', 'config-files');
                name = '';
            end
            app.configGUI = ConfigGUI(app, path, name);
        end
        
        % Given a wokrspace .mat file, the GUI reads it, gets the lists of
        % scans from the study directory, and sets up the rows on screen to
        % show the last saved workspace
        function success = ImportAndLoadWorkspace(app, name, path)
            success = 0;
            try
                workspace = load(fullfile(path, name), 'workspaceInfo');
                    
                if ~isequal(workspace.workspaceInfo.path, app.mrsStudyStruct.inputData)
                    uialert(app.GannetGUIV03UIFigure, 'The workspace you are attempting to use was created with different input data. Creating a new workspace.', 'Load Workspace Failed', 'Icon', 'error')
                    app.WorkspaceNameEditField.Value = 'New Workspace';
                    app.WorkspaceNameEditField.Tag = 'New';
                    BuildWorkspace(app, 1, 0);
                    success = 1;
                    return
                end

                ClearWorkspace(app);
                numRows = length(workspace.workspaceInfo.rows);
                BuildWorkspace(app, 0, numRows);
                EnableDisable(app, true);
                
                configFiles = app.mrsStudyStruct.configFiles;
                configList = regexprep(configFiles, '.mat', '');
                configAlert = 0;
                
                % Check to see if stored metab, ref, and t1 lists in the
                % workspace match the files found in the input data
                allRefs = {app.mrsStudyStruct.uniqueScans.ref};
                allT1s = {app.mrsStudyStruct.uniqueScans.t1};
                
                for workspaceRow = 1:length(workspace.workspaceInfo)
                    refsTheSame = 0;
                    for refOptions = 1:length(allRefs)
                        if isequal(allRefs{refOptions}, workspace.workspaceInfo.rows(workspaceRow).refList)
                            refsTheSame = 1;
                            break
                        end
                    end
                    
                    t1sTheSame = 0;
                    for t1Options = 1:length(allT1s)
                        if isequal(allT1s{refOptions}, workspace.workspaceInfo.rows(workspaceRow).t1List)
                            t1sTheSame = 1;
                            break
                        end
                    end

                    if (refsTheSame == 0) || (t1sTheSame == 0)
                        break
                    end
                end
               
                metabsTheSame = isequal([app.mrsStudyStruct.uniqueScans.metab], workspace.workspaceInfo.rows(1).metabList);

                if  metabsTheSame && refsTheSame && t1sTheSame
                    for row = 1:numRows
                        app.rowObjects.dataRows(row).metab.Items = workspace.workspaceInfo.rows(row).metabList;
                        app.rowObjects.dataRows(row).ref.Items = workspace.workspaceInfo.rows(row).refList;
                        app.rowObjects.dataRows(row).t1.Items = workspace.workspaceInfo.rows(row).t1List;
                        app.rowObjects.dataRows(row).metab.Value = workspace.workspaceInfo.rows(row).metab;
                        app.rowObjects.dataRows(row).ref.Value = workspace.workspaceInfo.rows(row).ref;
                        app.rowObjects.dataRows(row).t1.Value = workspace.workspaceInfo.rows(row).t1;

                        app.rowObjects.dataRows(row).config.Items = configList;
                        
                        if ismember(workspace.workspaceInfo.rows(row).config, configList)
                            app.rowObjects.dataRows(row).config.Value = workspace.workspaceInfo.rows(row).config;
                        else
                            configAlert = 1;
                        end
                    end
                    
                    if configAlert
                        uialert(app.GannetGUIV03UIFigure, 'When loading the Configuration Files, either a saved configuration file was not found in this directory; or there are new configuration files while none existed at the time the workspace was saved. Double check the loaded configuration files before continuing.', 'Configuration File Changed', 'Icon', 'warning')
                    end

                    app.WorkspaceNameEditField.Value = erase(name, ".mat");
                    app.WorkspaceNameEditField.Tag = 'Existing';

                    success = 1;
                else
                    if ~metabsTheSame
                        type = 'metabolite';
                    elseif ~refsTheSame
                        type = 'water reference';
                    else
                        type = 'anatomical image';
                    end
                    uialert(app.GannetGUIV03UIFigure, strcat("There was an error loading the selected workspace. The saved list of ", type, " files does not match the current set of ", type, " files in the directory."), 'Error Loading Workspace', 'Icon', 'error')
                    ClearWorkspace(app);
                end
            catch
                uialert(app.GannetGUIV03UIFigure, 'There was an error loading the selected workspace. Plase make sure all fields are properly named.', 'Error Loading Workspace', 'Icon', 'error')
                ClearWorkspace(app);
            end
        end
        
        function LoadDerivativesFolder(app, derivativesFolder)
            success = 1;

            ClearWorkspace(app);

            app.DerivativesFolderEditField.Value = derivativesFolder;
            app.mrsStudyStruct.derivativesFolder = derivativesFolder;

            configFiles = dir(fullfile(derivativesFolder, 'gannet', 'config-files', '*.mat'));
            configFileNames = [configFiles(1:end).name ""];
            app.mrsStudyStruct.configFiles = configFileNames(1:end - 1);

            workspaces = dir(fullfile(derivativesFolder, 'gannet', 'workspaces', '*.mat'));
            workspaceNames = [workspaces(1:end).name ""];
            app.mrsStudyStruct.workspaces = workspaceNames(1:end - 1);


            name = '';

            if ~isempty(app.mrsStudyStruct.workspaces)
                selection =uiconfirm(app.GannetGUIV03UIFigure,'Would you like to load an existing workspace or create a new one?','New or Existing Workspace?', ...
                    'Options',{'Create New Workspace','Load Exsiting Workspace'}, ...
                    'DefaultOption',1);
                if strcmp(selection, 'Load Exsiting Workspace')
                    workspaces = app.mrsStudyStruct.workspaces;
                    workspaceList = regexprep(workspaces, '.mat', '');

                    [idx, tf] = listdlg('ListString', workspaceList,...
                        'SelectionMode', 'Single', 'PromptString', 'Select Workspace', 'Initialvalue', 1,'Name', 'Workspaces Found');

                    if tf
                        name = app.mrsStudyStruct.workspaces(idx);
                        path = fullfile(derivativesFolder, 'gannet', 'workspaces');
                    end
                end
            end
            
            if ~isempty(name)
                app.WorkspaceNameEditField.Value = regexprep(name, '.mat', '');
                app.WorkspaceNameEditField.Tag = 'Existing';
                success = ImportAndLoadWorkspace(app, name, path);
            end
                
            if isempty(name) || ~success
                app.WorkspaceNameEditField.Value = 'New Workspace';
                app.WorkspaceNameEditField.Tag = 'New';
                BuildWorkspace(app, 1, 0);
            end

            app.WorkspaceNameEditField.Enable = true;
            EnableDisableContinue(app);
            EnableDisable(app, true);
        end
    end

    methods (Access = public)
        % Updates the list of config files in each config dropdown after
        % the ConfigGUI is closed
        function UpdateConfigList(app)
            configFiles = dir(fullfile(app.mrsStudyStruct.derivativesFolder, 'gannet', 'config-files', '*.mat'));
            configFileNames = [configFiles(1:end).name ""];
            configFileNames = configFileNames(1:end - 1);
            configList = regexprep(configFileNames, '.mat', '');

            app.mrsStudyStruct.configFiles = configFileNames;

            for row = 1:length(app.rowObjects.dataRows)
                app.rowObjects.dataRows(row).config.Items = configList;
            end

            try
                for row = 1:app.rowObjects.numRows
                    delete(app.rowObjects.dataRows(row).createConfig)
                    app.rowObjects.dataRows(row).config.Enable = true;
                    app.rowObjects.dataRows(row).config.Visible = true;
                end
            catch
            end
        end

        % Toggles the interactivity of the 'Continue' button, which should
        % only be enabled if each row in the table has a unique metab scan
        % sequence and a configuration file
        function EnableDisableContinue(app)
            metabDropdowns = [app.rowObjects.dataRows.metab];
            metabValues = [metabDropdowns.Value ""];

            numMetabs = length(unique(metabValues(1:end - 1)));

            if (numMetabs ~= length(metabDropdowns) || isempty(app.rowObjects.dataRows(1).config.Items))
                app.ContinueButton.Enable = false;
            else
                app.ContinueButton.Enable = true;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Size changed function: GannetGUIV03UIFigure
        function GannetGUIV03UIFigureSizeChanged(app, event)
            AdjustHeaderColumnWidths(app);
        end

        % Button pushed function: ResetWorkspaceButton
        function ResetWorkspaceButtonPushed(app, event)
            ClearWorkspace(app);
            BuildWorkspace(app, 1, 0);
            EnableDisable(app, true);
            EnableDisableContinue(app);
            
            app.WorkspaceNameEditField.Value = 'New Workspace';
            app.WorkspaceNameEditField.Tag = 'New';
        end

        % Button pushed function: CreateNewConfigurationButton
        function CreateNewConfigurationButtonPushed(app, event)
            CreateConfig(app, 'eventPlaceholder', fullfile(app.mrsStudyStruct.derivativesFolder, 'gannet', 'config-files'), '');
        end

        % Button pushed function: ViewEditExistingConfigurationButton
        function ViewEditExistingConfigurationButtonPushed(app, event)
            if ~isempty(app.mrsStudyStruct.configFiles)
                configFiles = app.mrsStudyStruct.configFiles;
                configList = regexprep(configFiles, '.mat', '');
                
                [idx, tf] = listdlg('ListString', configList,...
                    'SelectionMode', 'Single', 'PromptString', 'Select Configuration File', 'Initialvalue', 1,'Name', 'Configuration Files Found');
                if tf
                    name = app.mrsStudyStruct.configFiles(idx);
                    path = fullfile(app.mrsStudyStruct.derivativesFolder, 'gannet', 'config-files');
                    CreateConfig(app, 'eventPlaceholder', path, name);
                end
            else
                uialert(app.GannetGUIV03UIFigure, 'No configuration files were found in this study.', 'No Configuration Files Found', 'Icon', 'warning')
            end
        end

        % Button pushed function: ImportConfigurationButton
        function ImportConfigurationButtonPushed(app, event)
            configFilesFolder = fullfile(app.mrsStudyStruct.derivativesFolder, 'gannet', 'config-files');

            if exist(configFilesFolder, 'dir')
            else
                mkdir(configFilesFolder)
            end

            [name, path] = uigetfile('*.mat', 'Select a Configuration File');

            if name ~= 0
                if isfile(fullfile(path, name))
                    if isfile(fullfile(configFilesFolder, name))
                        uialert(app.GannetGUIV03UIFigure, 'There is already a configuration file with this name in the current study. Please re-name one of the files and try again.', 'File Already Exists', 'Icon', 'warning')
                    else
                        try
                            copyfile(fullfile(path, name), configFilesFolder)
                            uialert(app.GannetGUIV03UIFigure, 'The configuration file was successfully imported into the current study directory.', 'File Imported Successfully', 'Icon', 'success')
                        catch
                            uialert(app.GannetGUIV03UIFigure, 'Error copying file to the given study. Ensure that write access is enabled for the destination folder.', 'Error Copying File')
                        end
                    end
                end

                UpdateConfigList(app);
            end
        end

        % Close request function: GannetGUIV03UIFigure
        function GannetGUIV03UIFigureCloseRequest(app, event)
            try
                delete(app.configGUI)
            catch
            end
            delete(app)
        end

        % Button pushed function: HelpAboutButton
        function HelpAboutButtonPushed(app, event)
            fig = uifigure('Name', 'Help/About');
            fig.Position = [100, 100, 900, 400];

            gridLayout = uigridlayout(fig);
            gridLayout.RowHeight = {'1x'};
            gridLayout.ColumnWidth = {'1x'};

            textArea = uitextarea(gridLayout);

            fileID = fopen('ReadMe.txt', 'r');
            text = fscanf(fileID, '%c');
            textArea.Value = text;
            textArea.Editable = false;

            uialert(fig, ['GUIs built and designed by Noam Amozeg in the lab of Dr. Lamb at the University of Florida.', ...
                ' For help and information, please refer to the provided documentation.' newline, newline, 'For additional help, please contact Dr. Lamb at: dlamb@ufl.edu'], 'Help/About', 'Icon', 'info' ...
                )
        end

        % Button pushed function: SaveCurrentWorkspaceButton
        function SaveCurrentWorkspaceButtonPushed(app, event)
            name = inputdlg('Save file as (exclude .mat extension):','Save Workspace', 1, {convertStringsToChars(app.WorkspaceNameEditField.Value)});

            if ~isempty(strip(name))
                if ~strcmp(strip(name), "")
                    workspacesFolder = fullfile(app.mrsStudyStruct.derivativesFolder, 'gannet', 'workspaces');

                    if ~exist(workspacesFolder, 'dir')
                        mkdir(workspacesFolder);
                    end

                    workspaceInfo = struct;

                    workspaceInfo.path = app.mrsStudyStruct.inputData;

                    for row = 1:length(app.rowObjects.dataRows)
                        workspaceInfo.rows(row).metabList = app.rowObjects.dataRows(row).metab.Items;
                        workspaceInfo.rows(row).refList = app.rowObjects.dataRows(row).ref.Items;
                        workspaceInfo.rows(row).t1List = app.rowObjects.dataRows(row).t1.Items;
                        workspaceInfo.rows(row).configList = app.rowObjects.dataRows(row).config.Items;
                        workspaceInfo.rows(row).metab = app.rowObjects.dataRows(row).metab.Value;
                        workspaceInfo.rows(row).ref = app.rowObjects.dataRows(row).ref.Value;
                        workspaceInfo.rows(row).t1 = app.rowObjects.dataRows(row).t1.Value;
                        workspaceInfo.rows(row).config = app.rowObjects.dataRows(row).config.Value;
                    end
                    
                    
                    if ~strcmp(name, app.WorkspaceNameEditField.Value) && strcmp(app.WorkspaceNameEditField.Tag, 'Existing')
                        choice = uiconfirm(app.GannetGUIV03UIFigure, 'It looks like you renamed your workspace. Would you like to save this file as a re-named copy or re-name the original file?', 'Copy or Re-Name File?', 'Options', {'Re-Name File', 'Create a Copy'}, 'Icon', 'info');

                        if strcmp(choice, 'Re-Name File')
                            save(string(fullfile(workspacesFolder, name)), "workspaceInfo")
                            movefile(fullfile(workspacesFolder, strcat(app.WorkspaceNameEditField.Value, '.mat')), string(fullfile(workspacesFolder, strcat(name, '.mat'))))
                        else
                            save(string(fullfile(workspacesFolder, name)), "workspaceInfo")
                        end
                    end
                    
                    app.WorkspaceNameEditField.Value = name{1};
                    app.WorkspaceNameEditField.Tag = 'Existing';
                    
                    save(string(fullfile(workspacesFolder, name)), "workspaceInfo")
                    uialert(app.GannetGUIV03UIFigure, 'Your workspace was successfully saved.', 'Workspace Saved', 'Icon', 'success')
                    
                    workspaces = dir(fullfile(app.mrsStudyStruct.derivativesFolder, 'gannet', 'workspaces', '*.mat'));
                    workspaceNames = [workspaces(1:end).name ""];
                    app.mrsStudyStruct.workspaces = workspaceNames(1:end - 1);
                else
                    uialert(app.GannetGUIV03UIFigure, "Please enter a valid file name.", "Invalid File Name", "Icon", "warning")
                end
            end
        end

        % Button pushed function: LoadExistingWorkspaceButton
        function LoadExistingWorkspaceButtonPushed(app, event)
            if ~isempty(app.mrsStudyStruct.workspaces)
                workspaces = app.mrsStudyStruct.workspaces;
                workspaceList = regexprep(workspaces, '.mat', '');
                
                [idx, tf] = listdlg('ListString', workspaceList,...
                    'SelectionMode', 'Single', 'PromptString', 'Select Workspace', 'Initialvalue', 1,'Name', 'Workspaces Found');
                if tf
                    name = app.mrsStudyStruct.workspaces(idx);
                    path = fullfile(app.mrsStudyStruct.derivativesFolder, 'gannet', 'workspaces');
                    ImportAndLoadWorkspace(app, name, path);
                end

            else
                uialert(app.GannetGUIV03UIFigure, 'No workspaces were found in this study.', 'No Workspaces Found', 'Icon', 'warning')
            end

            EnableDisableContinue(app);
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            app.mrsStudyStruct.toBeProcessed = struct;

            for row = 1:length(app.rowObjects.dataRows)
                app.mrsStudyStruct.toBeProcessed(row).metab = app.rowObjects.dataRows(row).metab.Value;
                app.mrsStudyStruct.toBeProcessed(row).ref = app.rowObjects.dataRows(row).ref.Value;
                app.mrsStudyStruct.toBeProcessed(row).t1 = app.rowObjects.dataRows(row).t1.Value;
                app.mrsStudyStruct.toBeProcessed(row).config = app.rowObjects.dataRows(row).config.Value;
            end

            app.subjectSelectionGUI = SubjectSelectionGUI(app, app.mrsStudyStruct);
            app.GannetGUIV03UIFigure.Visible = false;
        end

        % Value changed function: InputDataEditField
        function InputDataEditFieldValueChanged(app, event)
            inputData = app.InputDataEditField.Value;

            if exist(inputData, 'dir')
                LoadInputData(app, inputData);
            else
                uialert(app.GannetGUIV03UIFigure, 'The given folder could not be found; returning edit box to previous value. Try browsing for the folder with the "Choose" button.', 'No Folder Found', 'Icon', 'warning')
                app.InputDataEditField.Value = event.PreviousValue;
            end
        end

        % Button pushed function: ChooseInputDataButton
        function ChooseInputDataButtonPushed(app, event)
            inputData = uigetdir(pwd, 'Select Input Data');

            if inputData ~= 0
                LoadInputData(app, inputData);
            end
        end

        % Value changed function: DerivativesFolderEditField
        function DerivativesFolderEditFieldValueChanged(app, event)
            derivativesFolder = app.DerivativesFolderEditField.Value;
            
            if exist(derivativesFolder, 'dir')
                LoadDerivativesFolder(app, derivativesFolder);
            else
                uialert(app.GannetGUIV03UIFigure, 'The given folder could not be found; returning edit box to previous value. Try browsing for the folder with the "Choose" button.', 'No Folder Found', 'Icon', 'warning')
                app.DerivativesFolderEditField.Value = event.PreviousValue;
            end
        end

        % Button pushed function: ChooseDerivativesFolderButton
        function ChooseDerivativesFolderButtonPushed(app, event)
            derivativesFolder = uigetdir(pwd, 'Select Derivatives Folder');

            if derivativesFolder ~= 0
                LoadDerivativesFolder(app, derivativesFolder);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GannetGUIV03UIFigure = uifigure('Visible', 'off');
            app.GannetGUIV03UIFigure.Name = 'Gannet GUI V0.3';
            app.GannetGUIV03UIFigure.CloseRequestFcn = createCallbackFcn(app, @GannetGUIV03UIFigureCloseRequest, true);
            app.GannetGUIV03UIFigure.SizeChangedFcn = createCallbackFcn(app, @GannetGUIV03UIFigureSizeChanged, true);
            app.MainGridLayout = uigridlayout(app.GannetGUIV03UIFigure);
            app.StudySelectionGridLayout = uigridlayout(app.MainGridLayout);
            app.GannetLogo = uiimage(app.StudySelectionGridLayout);
            app.GannetLogo.ImageSource = 'Gannet Logo.png';
            app.NameAndPathPanel = uipanel(app.StudySelectionGridLayout);
            app.NameAndPathGridLayout = uigridlayout(app.NameAndPathPanel);
            app.InputDataLabel = uilabel(app.NameAndPathGridLayout);
            app.InputDataLabel.Text = 'Input Data:';
            app.GridLayout6 = uigridlayout(app.NameAndPathGridLayout);
            app.ChooseInputDataButton = uibutton(app.GridLayout6, 'push');
            app.ChooseInputDataButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseInputDataButtonPushed, true);
            app.ChooseInputDataButton.Text = 'Choose';
            app.InputDataEditField = uieditfield(app.GridLayout6, 'text');
            app.InputDataEditField.ValueChangedFcn = createCallbackFcn(app, @InputDataEditFieldValueChanged, true);
            app.DerivativesFolderLabel = uilabel(app.NameAndPathGridLayout);
            app.DerivativesFolderLabel.Text = 'Derivatives Folder:';
            app.WorkspaceLabel = uilabel(app.NameAndPathGridLayout);
            app.WorkspaceLabel.Text = 'Workspace:';
            app.GridLayout7 = uigridlayout(app.NameAndPathGridLayout);
            app.DerivativesFolderEditField = uieditfield(app.GridLayout7, 'text');
            app.DerivativesFolderEditField.ValueChangedFcn = createCallbackFcn(app, @DerivativesFolderEditFieldValueChanged, true);
            app.DerivativesFolderEditField.Enable = 'off';
            app.ChooseDerivativesFolderButton = uibutton(app.GridLayout7, 'push');
            app.ChooseDerivativesFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseDerivativesFolderButtonPushed, true);
            app.ChooseDerivativesFolderButton.Enable = 'off';
            app.ChooseDerivativesFolderButton.Text = 'Choose';
            app.WorkspaceNameEditField = uieditfield(app.NameAndPathGridLayout, 'text');
            app.WorkspaceNameEditField.Editable = 'off';
            app.WorkspaceNameEditField.Enable = 'off';
            app.HelpAboutButton = uibutton(app.StudySelectionGridLayout, 'push');
            app.HelpAboutButton.ButtonPushedFcn = createCallbackFcn(app, @HelpAboutButtonPushed, true);
            app.HelpAboutButton.Text = 'Help/About';
            app.DataSelectionPanel = uipanel(app.MainGridLayout);
            app.DataAndHeaderGridLayout = uigridlayout(app.DataSelectionPanel);
            app.HeaderGridLayout = uigridlayout(app.DataAndHeaderGridLayout);
            app.MetaboliteFileLabel = uilabel(app.HeaderGridLayout);
            app.MetaboliteFileLabel.Text = 'Metabolite File';
            app.WaterReferenceFileLabel = uilabel(app.HeaderGridLayout);
            app.WaterReferenceFileLabel.Text = 'Water Reference File';
            app.AnatomicalImageFileLabel = uilabel(app.HeaderGridLayout);
            app.AnatomicalImageFileLabel.Text = 'Anatomical Image File';
            app.ConfigurationFileLabel = uilabel(app.HeaderGridLayout);
            app.ConfigurationFileLabel.Text = 'Configuration File';
            app.RemoveRowLabel = uilabel(app.HeaderGridLayout);
            app.RemoveRowLabel.Text = {'Remove'; 'Row'};
            app.DataGridLayout = uigridlayout(app.DataAndHeaderGridLayout);
            app.BottomGridLayout = uigridlayout(app.MainGridLayout);
            app.ContinueButton = uibutton(app.BottomGridLayout, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Enable = 'off';
            app.ContinueButton.Text = 'Continue';
            app.GridLayout5 = uigridlayout(app.BottomGridLayout);
            app.ResetWorkspaceButton = uibutton(app.GridLayout5, 'push');
            app.ResetWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ResetWorkspaceButtonPushed, true);
            app.ResetWorkspaceButton.Enable = 'off';
            app.ResetWorkspaceButton.Text = 'Reset Workspace';
            app.CreateNewConfigurationButton = uibutton(app.GridLayout5, 'push');
            app.CreateNewConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewConfigurationButtonPushed, true);
            app.CreateNewConfigurationButton.Enable = 'off';
            app.CreateNewConfigurationButton.Text = {'Create New'; 'Configuration'};
            app.SaveCurrentWorkspaceButton = uibutton(app.GridLayout5, 'push');
            app.SaveCurrentWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCurrentWorkspaceButtonPushed, true);
            app.SaveCurrentWorkspaceButton.Enable = 'off';
            app.SaveCurrentWorkspaceButton.Text = {'Save Current'; 'Workspace'};
            app.ImportConfigurationButton = uibutton(app.GridLayout5, 'push');
            app.ImportConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @ImportConfigurationButtonPushed, true);
            app.ImportConfigurationButton.Enable = 'off';
            app.ImportConfigurationButton.Text = {'Import'; 'Configuration'};
            app.ViewEditExistingConfigurationButton = uibutton(app.GridLayout5, 'push');
            app.ViewEditExistingConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @ViewEditExistingConfigurationButtonPushed, true);
            app.ViewEditExistingConfigurationButton.Enable = 'off';
            app.ViewEditExistingConfigurationButton.Text = {'View/Edit Existing'; 'Configuration'};
            app.LoadExistingWorkspaceButton = uibutton(app.GridLayout5, 'push');
            app.LoadExistingWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExistingWorkspaceButtonPushed, true);
            app.LoadExistingWorkspaceButton.Enable = 'off';
            app.LoadExistingWorkspaceButton.Text = {'Load Existing'; 'Workspace'};
            % Show the figure after all components are created
            app.GannetGUIV03UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GannetGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GannetGUIV03UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GannetGUIV03UIFigure)
        end
    end
end