classdef SubjectSelectionGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SubjectandFunctionSelectorGannetGUIV03UIFigure  matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        GridLayout2              matlab.ui.container.GridLayout
        StudyDirectoryPathLabel  matlab.ui.control.Label
        RunGannetButton          matlab.ui.control.Button
        TabGroup                 matlab.ui.container.TabGroup
        Tab                      matlab.ui.container.Tab
    end

    
    properties (Access = private)
        mrsStudyStruct;  % Same structure from first GUI
        gannetGUI;       % GUI that called this GUI
        tabs;            % Structure that stores the information held in each tab
    end
    
    methods (Access = private)
        
        % Given a tab number, check which subjects should be built and
        % build the tab with those who should
        function BuildTab(app, tabNum)
            gridLayout = app.tabs(tabNum).gridLayout;
            fileGrid = uigridlayout(gridLayout);
            fileGrid.Layout.Row = 3;
            fileGrid.RowHeight = zeros(1, sum(app.tabs(tabNum).shouldBeBuilt)) + 40;
            fileGrid.ColumnWidth = {'1x', '2x', '4.2x', '4.2x', '4.2x'};
            fileGrid.Scrollable = true;
            fileGrid.Padding = [0 0 0 0];
            
            app.tabs(tabNum).fileGrid = fileGrid;
            builtRows = 1;
            
            for subject = 1:length(app.tabs(tabNum).shouldBeBuilt)
                if app.tabs(tabNum).shouldBeBuilt(subject)
                    includedCheckboxGridLayout = uigridlayout(fileGrid);
                    includedCheckboxGridLayout.Layout.Row = builtRows;
                    includedCheckboxGridLayout.RowHeight = {'1x'};
                    includedCheckboxGridLayout.ColumnWidth = 14;
                    includedCheckboxGridLayout.ColumnSpacing = 0;
                    includedCheckboxGridLayout.Scrollable = false;

                    position = app.SubjectandFunctionSelectorGannetGUIV03UIFigure.Position;
                    columnWidth = floor((position(3) - 60)/15.6);

                    padding = (columnWidth - 14)/2;
                    includedCheckboxGridLayout.Padding = [padding 0 padding 0];

                    includedCheckbox = uicheckbox(includedCheckboxGridLayout);
                    includedCheckbox.Text = '';
                    includedCheckbox.Layout.Row = 1;
                    includedCheckbox.Layout.Column = 1;
                    includedCheckbox.Value = app.tabs(tabNum).included(subject);
                    includedCheckbox.Enable = app.tabs(tabNum).enabled(subject);
                    includedCheckbox.ValueChangedFcn = createCallbackFcn(app, @IncludedExcluded, true);
                    includedCheckbox.Tag = strcat(int2str(tabNum), '-', int2str(subject));
                    
                    subName = uilabel(fileGrid);
                    subName.Text = app.tabs(tabNum).data(subject).subject;
                    subName.HorizontalAlignment = 'center';
                    subName.VerticalAlignment = 'center';
                    subName.Layout.Row = builtRows;
                    subName.Layout.Column = 2;
                    
                    app.tabs(tabNum).uielements(subject).includedCheckboxGridLayout = includedCheckboxGridLayout;
                    app.tabs(tabNum).uielements(subject).includedCheckbox = includedCheckbox;
                    app.tabs(tabNum).uielements(subject).subNameLabel = subName;
                    
                    fieldNames = {'metab', 'ref', 't1'};
                    
                    for file = 1:3
                        label = uilabel(fileGrid);
                        label.Layout.Row = builtRows;
                        label.Layout.Column = 2 + file;
                        label.Text = app.tabs(tabNum).data(subject).(fieldNames{file}).fileName;
                        label.HorizontalAlignment = 'center';
                        label.VerticalAlignment = 'center';
                        
                        if app.tabs(tabNum).data(subject).(fieldNames{file}).exists
                            label.BackgroundColor = [0.67 0.94 0.71];
                        else
                            label.BackgroundColor = [0.96 0.61 0.46];
                        end
                        
                        app.tabs(tabNum).uielements(subject).(fieldNames{file}) = label;
                    end
                    
                    builtRows = builtRows + 1;
                end
            end
        end
        
        % When a subject's inclusion status is changed, the table may need
        % to be updated based on which subjects should be currently visible
        function UpdateTable(app, tabNum, subject)
            app.tabs(tabNum).uielements(subject).includedCheckbox.Visible = false;
            app.tabs(tabNum).uielements(subject).subNameLabel.Visible = false;
            app.tabs(tabNum).uielements(subject).metab.Visible = false;
            app.tabs(tabNum).uielements(subject).ref.Visible = false;
            app.tabs(tabNum).uielements(subject).t1.Visible = false;
            
            for row = subject + 1:length(app.tabs(tabNum).uielements)
                try
                    rowNum = app.tabs(tabNum).uielements(row).includedCheckboxGridLayout.Layout.Row - 1;
                    app.tabs(tabNum).uielements(row).includedCheckboxGridLayout.Layout.Row = rowNum;
                    app.tabs(tabNum).uielements(row).subNameLabel.Layout.Row = rowNum;
                    app.tabs(tabNum).uielements(row).metab.Layout.Row = rowNum;
                    app.tabs(tabNum).uielements(row).ref.Layout.Row = rowNum;
                    app.tabs(tabNum).uielements(row).t1.Layout.Row = rowNum;
                catch
                end
            end
        end
        
        % Clicking on the checkbox toggles the inclusion status of a
        % subject in the tab structure and may change which tabs should be
        % built
        function IncludedExcluded(app, event)
            tag = event.Source.Tag;
            tabNum = str2double(extractBefore(tag, '-'));
            subject = str2double(extractAfter(tag, '-'));
            
            app.tabs(tabNum).included(subject) = ~app.tabs(tabNum).included(subject);
            
            if ~strcmp(app.tabs(tabNum).currentlyShowing.Value, 'All Subjects')
                if strcmp(app.tabs(tabNum).currentlyShowing.Value, 'Included Subjects')
                    app.tabs(tabNum).shouldBeBuilt = [app.tabs(tabNum).included];
                else
                    app.tabs(tabNum).shouldBeBuilt = ~[app.tabs(tabNum).included];
                end
                
                UpdateTable(app, tabNum, subject);
            end
        end
        
        % When the 'Currently Showing:' dropdown is changed, the selected
        % tab should be rebuilt to match the dropdown selection
        function CurrentlyShowing(app, event)
            tabNum = str2double(event.Source.Tag);
            size = app.mrsStudyStruct.numSubs;
            
            if strcmp(event.Source.Value, 'All Subjects')
                app.tabs(tabNum).shouldBeBuilt = ones(1, size);
            elseif strcmp(event.Source.Value, 'Included Subjects')
                app.tabs(tabNum).shouldBeBuilt = [app.tabs(tabNum).included];
            else
                app.tabs(tabNum).shouldBeBuilt = ~[app.tabs(tabNum).included];
            end
            
            ClearTab(app, tabNum);
            BuildTab(app, tabNum);
        end
        
        % By deleting the grid layout that holds UI elements, the UI
        % elements are also deleted
        function ClearTab(app, tabNum)
            delete(app.tabs(tabNum).fileGrid);
        end
        
        % Updates which subjects should be shown and included based on
        % which Gannet functions the user wants
        function ChooseFunctionsChanged(app, event)
            tabNum = str2double(event.Source.Tag);
            metabFiles = [app.tabs(tabNum).data(1:end).metab];
            refFiles = [app.tabs(tabNum).data(1:end).ref];
            t1Files = [app.tabs(tabNum).data(1:end).t1];

            if any(strcmp(app.tabs(tabNum).chooseFunctions.Value, ["GannetCoRegister" "GannetSegment"]))
                app.tabs(tabNum).included = [t1Files.exists] & [metabFiles.exists];
                app.tabs(tabNum).enabled = [t1Files.exists] & [metabFiles.exists];
            elseif strcmp(app.tabs(tabNum).chooseFunctions.Value, "GannetQuantify")
                app.tabs(tabNum).included = [t1Files.exists] & [refFiles.exists] & [metabFiles.exists];
                app.tabs(tabNum).enabled = [t1Files.exists] & [refFiles.exists] & [metabFiles.exists];
            else
                app.tabs(tabNum).included = [metabFiles.exists];
                app.tabs(tabNum).enabled = [metabFiles.exists];
            end
            
            size = app.mrsStudyStruct.numSubs;
            
            if strcmp(app.tabs(tabNum).currentlyShowing.Value, 'All Subjects')
                app.tabs(tabNum).shouldBeBuilt = ones(1, size);
            elseif strcmp(app.tabs(tabNum).currentlyShowing.Value, 'Included Subjects')
                app.tabs(tabNum).shouldBeBuilt = [app.tabs(tabNum).included];
            else
                app.tabs(tabNum).shouldBeBuilt = ~[app.tabs(tabNum).included];
            end
            
            ClearTab(app, tabNum);
            BuildTab(app, tabNum);
        end
        
        function IncludeExcludeAll(app, event)
            tabNum = str2double(event.Source.Tag);
            metabFiles = [app.tabs(tabNum).data(1:end).metab];
            refFiles = [app.tabs(tabNum).data(1:end).ref];
            t1Files = [app.tabs(tabNum).data(1:end).t1];

            if sum(app.tabs(tabNum).included) == 0
                if any(strcmp(app.tabs(tabNum).chooseFunctions.Value, ["GannetCoRegister" "GannetSegment"]))
                    app.tabs(tabNum).included = [t1Files.exists] & [metabFiles.exists];
                elseif strcmp(app.tabs(tabNum).chooseFunctions.Value, "GannetQuantify")
                    app.tabs(tabNum).included = [t1Files.exists] & [refFiles.exists] & [metabFiles.exists];
                else
                    app.tabs(tabNum).included = [metabFiles.exists];
                end
            else
                app.tabs(tabNum).included = zeros(1,length(app.tabs(tabNum).included));
            end
            
            if ~strcmp(app.tabs(tabNum).currentlyShowing.Value, 'All Subjects')
                if strcmp(app.tabs(tabNum).currentlyShowing.Value, 'Included Subjects')
                    app.tabs(tabNum).shouldBeBuilt = [app.tabs(tabNum).included];
                else
                    app.tabs(tabNum).shouldBeBuilt = ~[app.tabs(tabNum).included];
                end
            end
            
            ClearTab(app, tabNum);
            BuildTab(app, tabNum);
        end
        
        function errorList = RunGannet(app, tab, currentConfigPath, metabFilePaths, refFilePaths, t1FilePaths, included)
            if strcmp(app.tabs(tab).chooseFunctions.Value, 'GannetLoad')
                metabFilePaths = metabFilePaths(included);
                [MRS, errorList] = GannetLoad(true, currentConfigPath, metabFilePaths);
            else
                % Check if all Included subjects also have existing
                % reference files
                refFiles = [app.tabs(tab).data.ref];
                refExists = [refFiles.exists];
                includedWithRefs = included & refExists;
                allIncludedRefsExist = all(included == includedWithRefs);
                anyReferences = any(refExists);

                % If some passed in subjects have water reference
                % files and some do not, only run Gannet on those
                % that DO have the references
                if ~allIncludedRefsExist && anyReferences
                    uialert(app.SubjectandFunctionSelectorGannetGUIV03UIFigure, strcat("Some included subjects were missing water reference files. Running GannetLoad only for subjects with reference files for tab number ", int2str(tab), "."), 'Missing Water Reference Files', 'Icon', 'warning')
                end
                
                % If there are no reference files passed at all, run
                % GannetLoad only with metabolite files
                if anyReferences
                    metabFilePaths = metabFilePaths(includedWithRefs);
                    refFilePaths = refFilePaths(includedWithRefs);
                    t1FilePaths = t1FilePaths(includedWithRefs);

                    [MRS, errorList] = GannetLoad(true, currentConfigPath, metabFilePaths, refFilePaths);
                else
                    metabFilePaths = metabFilePaths(included);
                    t1FilePaths = t1FilePaths(included);
                    [MRS, errorList] = GannetLoad(true, currentConfigPath, metabFilePaths);
                end

                if ~isempty(errorList)
                    uialert(app.SubjectandFunctionSelectorGannetGUIV03UIFigure, strcat("There was an error in GannetLoad. Stopping Gannet on tab number ", int2str(tab), "."), 'Error Loading Subject(s)', 'Icon', 'error')
                    return
                end

                MRS = GannetFit(MRS);

                if strcmp(app.tabs(tab).chooseFunctions.Value, 'GannetCoRegister')
                    MRS = GannetCoRegister(MRS, t1FilePaths);
                end

                if strcmp(app.tabs(tab).chooseFunctions.Value, 'GannetSegment')
                    MRS = GannetCoRegister(MRS, t1FilePaths);
                    MRS = GannetSegment(MRS);
                end

                if strcmp(app.tabs(tab).chooseFunctions.Value, 'GannetQuantify')
                    MRS = GannetCoRegister(MRS, t1FilePaths);
                    MRS = GannetSegment(MRS);
                    MRS = GannetQuantify(MRS);
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, gannetGUI, mrsStudyStruct)
            % Given the information from the previous GUI, builds a tab for
            % each metab sequence and shows the expected files for each
            % subject and wether they exist or not
            app.gannetGUI = gannetGUI;
            app.mrsStudyStruct = mrsStudyStruct;
            app.tabs = struct;
            app.StudyDirectoryPathLabel.Text = strcat("Study Directory Path: ", app.mrsStudyStruct.inputData);
            
            for metab = 1:length(app.mrsStudyStruct.toBeProcessed)
                currentTab = uitab(app.TabGroup);
                currentTab.Title = app.mrsStudyStruct.toBeProcessed(metab).metab;
                gridLayout = uigridlayout(currentTab);
                gridLayout.RowHeight = {40, 40, '6x', '1x'};
                gridLayout.ColumnWidth = {'1x'};
                
                configLabel = uilabel(gridLayout);
                configLabel.Layout.Row = 1;
                configLabel.Text = strcat("Configuration File: ", app.mrsStudyStruct.toBeProcessed(metab).config);
                
                headers = uigridlayout(gridLayout);
                headers.Layout.Row = 2;
                headers.RowHeight = {'1x'};
                headers.Padding = [0 0 0 0];
                headers.ColumnWidth = {'1x', '2x', '4.2x', '4.2x', '4.2x'};
                
                headerText = {'Included', 'Subject', 'Metabolite File', 'Reference File', 'T1 File'};
                
                for column = 1:5
                    label = uilabel(headers);
                    label.Layout.Column = column;
                    label.Text = headerText{column};
                    label.FontWeight = 'bold';
                    label.HorizontalAlignment = 'center';
                    label.VerticalAlignment = 'center';
                end
                
                fieldNames = {'metab', 'ref', 't1'};
                
                app.tabs(metab).included = zeros(1, app.mrsStudyStruct.numSubs);
                app.tabs(metab).enabled = zeros(1, app.mrsStudyStruct.numSubs);
                
                for subject = 1:app.mrsStudyStruct.numSubs
                    subName = app.mrsStudyStruct.subjects(subject).subName;
                    
                    app.tabs(metab).data(subject).subject = subName;
                    
                    % Check each subject to see if they have metab, ref,
                    % and/or t1 files
                    for field = 1:3
                        fieldName = fieldNames{field};
                        fileType = app.mrsStudyStruct.toBeProcessed(metab).(fieldName);
                        
                        if ~strcmp(fileType, 'None')
                            fileName = strcat(subName, '_', fileType);
                            columnName = app.mrsStudyStruct.key(fileType);
                            exists = app.mrsStudyStruct.allScansTable(subject).(columnName).isFile;
                            path = app.mrsStudyStruct.allScansTable(subject).(columnName).filePath;
                        else
                            fileName = fileType;
                            exists = false;
                            path = "None";
                        end
                        
                        % On startup, Enable and Include subjects whose metab file
                        % exists
                        if field == 1
                            app.tabs(metab).enabled(subject) = exists;
                            app.tabs(metab).included(subject) = exists;
                        end
                        
                        app.tabs(metab).data(subject).(fieldName).fileName = fileName;
                        app.tabs(metab).data(subject).(fieldName).path = path;
                        app.tabs(metab).data(subject).(fieldName).exists = exists;
                    end
                end
                
                app.tabs(metab).shouldBeBuilt = ones(1,app.mrsStudyStruct.numSubs);
                
                app.tabs(metab).gridLayout = gridLayout;
                
                BuildTab(app, metab)
                
                bottomLayout = uigridlayout(gridLayout);
                bottomLayout.Layout.Row = 4;
                bottomLayout.ColumnWidth = {'1.5x', '0.9x', '0.7x', '0.9x', '1.1x'};
                bottomLayout.RowHeight = {'1x'};
                bottomLayout.Padding = [0 0 0 0];
                
                chooseFunctionsLabel = uilabel(bottomLayout);
                chooseFunctionsLabel.Text = 'At which step do you want to stop Gannet?';
                chooseFunctionsLabel.Layout.Column = 1;
                
                chooseFunctionsDropdown = uidropdown(bottomLayout, 'Items', {'GannetLoad', 'GannetFit', 'GannetCoRegister', 'GannetSegment', 'GannetQuantify'});
                chooseFunctionsDropdown.Layout.Column = 2;
                chooseFunctionsDropdown.Tag = int2str(metab);
                chooseFunctionsDropdown.ValueChangedFcn = createCallbackFcn(app, @ChooseFunctionsChanged, true);
                
                currentlyShowingLabel = uilabel(bottomLayout);
                currentlyShowingLabel.Text = 'Currently showing:';
                currentlyShowingLabel.Layout.Column = 3;
                
                currentlyShowingDropdown = uidropdown(bottomLayout);
                currentlyShowingDropdown.Layout.Column = 4;
                currentlyShowingDropdown.Items = {'All Subjects', 'Included Subjects', 'Excluded Subjects'};
                currentlyShowingDropdown.ValueChangedFcn = createCallbackFcn(app, @CurrentlyShowing, true);
                currentlyShowingDropdown.Tag = int2str(metab);

                includeExcludeAllButton = uibutton(bottomLayout);
                includeExcludeAllButton.Layout.Column = 5;
                includeExcludeAllButton.Text = 'Include/Exclude All Subjects';
                includeExcludeAllButton.ButtonPushedFcn = createCallbackFcn(app, @IncludeExcludeAll, true);
                includeExcludeAllButton.Tag = int2str(metab);
                
                app.tabs(metab).currentlyShowing = currentlyShowingDropdown;
                app.tabs(metab).chooseFunctions = chooseFunctionsDropdown;
                app.tabs(metab).includeExcludeAll = includeExcludeAllButton;
            end
            
            delete(app.Tab);
        end

        % Button pushed function: RunGannetButton
        function RunGannetButtonPushed(app, event)
            % When the button is pushed, the selected Gannet functions
            % should be run on the selected subjects. Note that running
            % GannetFit requires either all included subjects to have ref
            % files or no included subjects to have ref files
            choice = uiconfirm(app.SubjectandFunctionSelectorGannetGUIV03UIFigure, 'Continuing runs Gannet for all tabs currently open. Make sure everything is formatted as nedded before continuing.', 'Continue?', 'Options', {'Cancel', 'Continue'});
            
            if strcmp(choice, 'Continue')
                cd(fullfile(app.mrsStudyStruct.derivativesFolder, 'gannet'))
                
                uialert(app.SubjectandFunctionSelectorGannetGUIV03UIFigure, 'Gannet is currently running. Please do not close this window.', 'Running Gannet', 'Icon', 'info')
                
                % Keeping track of which tabs had no errors
                tabsPassed = ones(1, length(app.tabs));

                for tab = 1:length(app.tabs)
                    currentConfigPath = fullfile(app.mrsStudyStruct.derivativesFolder, 'gannet', 'config-files', app.mrsStudyStruct.toBeProcessed(tab).config);
                    included = app.tabs(tab).included;
                    included = logical(included);

                    metabStructure = [app.tabs(tab).data(1:end).metab];
                    metabFilePaths = [metabStructure.path];
                    
                    refStructure = [app.tabs(tab).data(1:end).ref];
                    refFilePaths = [refStructure.path];

                    t1Structure = [app.tabs(tab).data(1:end).t1];
                    t1FilePaths = [t1Structure.path];
                    
                    load(currentConfigPath);
                    

                    % If missing read/write acces to data, copy t1 files so
                    % coregister and future functions can run. Update this
                    % so matlab can automatically check if you have access
                    if ~configFile.readWriteAccess
                        disp("Since you do not have Read or Write Access to the data, it will be copied into your Derivatives Folder to be processed.")
                        working  = fullfile(app.mrsStudyStruct.derivativesFolder, 'gannet', 'working-files');
                        if exist(working, 'dir')
                            rmdir(working, 's');
                        end
                        mkdir(working)
                        
                        for fileNum = 1:length(metabFilePaths)
                            if included(fileNum) == 1
                                copyfile(metabFilePaths(fileNum), working);
                                disp(strcat("Copying file: ", metabFilePaths(fileNum)))

                                try
                                    copyfile(refFilePaths(fileNum), working);
                                    disp(strcat("Copying file: ", refFilePaths(fileNum)))
                                catch
                                end

                                try
                                    copyfile(t1FilePaths(fileNum), working);
                                    disp(strcat("Copying file: ", t1FilePaths(fileNum)))
                                catch
                                end
                            end
                        end

                        [t1Folders, ~] = fileparts(t1FilePaths);
                        [metabFolders, ~] = fileparts(metabFilePaths);
                        [refFolders, ~] = fileparts(refFilePaths);

                        t1FilePaths = regexprep(t1FilePaths, t1Folders, working);
                        metabFilePaths = regexprep(metabFilePaths, metabFolders, working);
                        refFilePaths = regexprep(refFilePaths, refFolders, working);

                        t1FilePaths = cellstr(t1FilePaths);
                        metabFilePaths = cellstr(metabFilePaths);
                        refFilePaths = cellstr(refFilePaths);
                    end

                    % TO DO: Update to check if subjects had errors, do not
                    % run next Gannet function on those subjects. For now,
                    % have to re-run previous Gannet steps

                    errorList = RunGannet(app, tab, currentConfigPath, metabFilePaths, refFilePaths, t1FilePaths, included);

                    if ~isempty(errorList)
                        tabsPassed(tab) = 0;
                        msgPart1 = strcat("The following subjects had errors in GannetLoad on tab number ", int2str(tab), "; consider re-running Gannet without them included. Check Gannet's error outputs for more information.");
                        uialert(app.SubjectandFunctionSelectorGannetGUIV03UIFigure, strjoin([msgPart1 strjoin(errorList, ", ") "Note: Sometimes an error in one subject appears to affect the next one, so it is worth checking each subject on this list individually."], [newline newline]), 'Error Loading Subject(s)', 'Icon', 'warning')
                    end
                end
                
                tabsFailed = find(tabsPassed == 0);
                if isempty(tabsFailed)
                    tabsFailed = "None";
                else
                    tabsFailed = strjoin(string(tabsFailed), ", ");
                end

                uialert(app.SubjectandFunctionSelectorGannetGUIV03UIFigure, strjoin(["Finished running Gannet. You may now close the GUI. There were errors on the following tabs:" tabsFailed], [newline newline]), 'Success', 'Icon', 'success')
            end
        end

        % Size changed function: 
        % SubjectandFunctionSelectorGannetGUIV03UIFigure
        function SubjectandFunctionSelectorGannetGUIV03UIFigureSizeChanged(app, event)
            % Checkboxes do not center themselves inside grid layouts in
            % Matlab, so this function adjusts the padding around each
            % checkbox to keep it centered as the windo size is changed

            % columnWidth is the window width minus padding, divided by
            % total "units" in ColumnWidth description for column headers
            % {'1x', '2x', '4.2x', '4.2x', '4.2x'}
            position = app.SubjectandFunctionSelectorGannetGUIV03UIFigure.Position;
            columnWidth = floor((position(3) - 60)/15.6);
            
            % A checkbox is 14 pixels (I counted...)
            padding = (columnWidth - 14)/2;

            for tabNum = 1:length(app.tabs)
                for rowNum = 1:app.mrsStudyStruct.numSubs
                    % Try-catch statement is because based on which
                    % subjects you are viewing (All, Included, or
                    % Excluded). some rows might not exist and therefore
                    % their checkboxes can not be centered

                    % Instead of looping only through the rows that are
                    % actually built, this works for now. It could
                    % definitely be changed, but not a priority
                    try
                        app.tabs(tabNum).uielements(rowNum).includedCheckboxGridLayout.Padding = [padding 0 padding 0];
                    catch
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SubjectandFunctionSelectorGannetGUIV03UIFigure = uifigure('Visible', 'off');
            app.SubjectandFunctionSelectorGannetGUIV03UIFigure.Name = 'Subject and Function Selector | Gannet GUI V0.3';
            app.SubjectandFunctionSelectorGannetGUIV03UIFigure.SizeChangedFcn = createCallbackFcn(app, @SubjectandFunctionSelectorGannetGUIV03UIFigureSizeChanged, true);
            app.GridLayout = uigridlayout(app.SubjectandFunctionSelectorGannetGUIV03UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.RunGannetButton = uibutton(app.GridLayout2, 'push');
            app.RunGannetButton.ButtonPushedFcn = createCallbackFcn(app, @RunGannetButtonPushed, true);
            app.RunGannetButton.Text = 'Run Gannet';
            app.StudyDirectoryPathLabel = uilabel(app.GridLayout2);
            app.StudyDirectoryPathLabel.Text = 'Study Directory Path:';
            % Show the figure after all components are created
            app.SubjectandFunctionSelectorGannetGUIV03UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SubjectSelectionGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SubjectandFunctionSelectorGannetGUIV03UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SubjectandFunctionSelectorGannetGUIV03UIFigure)
        end
    end
end